classdef UI_test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        OutputText                   matlab.ui.control.TextArea
        TabGroup                     matlab.ui.container.TabGroup
        SimulationsTab               matlab.ui.container.Tab
        SelectstartandfinishpointsButton  matlab.ui.control.Button
        CalculatepathButton          matlab.ui.control.Button
        StartSimulationButton        matlab.ui.control.Button
        OptionsTab                   matlab.ui.container.Tab
        ShowStructureCheckBox        matlab.ui.control.CheckBox
        ShowStopsignsCheckBox        matlab.ui.control.CheckBox
        ShowSpeedLimitsignsCheckBox  matlab.ui.control.CheckBox
        ShowPedestrianCrossingsignsCheckBox  matlab.ui.control.CheckBox
        Panel                        matlab.ui.container.Panel
        BatteryGaugeLabel            matlab.ui.control.Label
        BatteryGauge                 matlab.ui.control.LinearGauge
        Controls                     matlab.ui.container.Panel
        VelocityLabel                matlab.ui.control.Label
        VelocityOutText              matlab.ui.control.EditField
        PhiLabel                     matlab.ui.control.Label
        PhiOutText                   matlab.ui.control.EditField
        StopSimulationsButton        matlab.ui.control.Button
        ClearPlotButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        start_end_points=[]; % Start and finish point coordinates
        G;
        x_path = [] 
        Ggraph = plot([]);
        
        stop_signs;
        stop_signs_scatter;
        
        speed_limit_signs;
        speed_limit_signs_scatter;
        speed_limit_signs_scatter1;
        
        pedestrian_crossing_signs;
        pedestrian_crossing_signs_scatter;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes, 'on')
            imshow('ist_map_detail.png','Parent',app.UIAxes);
            
            %LOADS GRAPH STRUCTURE
            app.G = load('G');
            app.G = app.G.G;
            
            %LOAD STOP SIGNS POSITIONS
            app.stop_signs = load('stop_signs');
            app.stop_signs = app.stop_signs.stop_signs;
            
            %SPEED LIMIT SIGNS 
            app.speed_limit_signs = load('speed_limit_signs');
            app.speed_limit_signs = app.speed_limit_signs.speed_limit_signs;
            
            app.pedestrian_crossing_signs = load('pedestrian_crossing_signs');
            app.pedestrian_crossing_signs = app.pedestrian_crossing_signs.pedestrian_crossing_signs;
            
            app.OutputText.Enable = 'on';
            app.OutputText.Editable = 'off';
            app.OutputText.Value = "Program initialized successfully!";
        end

        % Button pushed function: SelectstartandfinishpointsButton
        function SelectstartandfinishpointsButtonPushed(app, event)
       
        
        empty = isempty(app.start_end_points);
        app.OutputText.Value = "Select the starting point in the map"
        figure();    
        imshow('ist_map_detail.png');
        hold on;
            
            for i=1:2
                if i == 2
                    app.OutputText.Value = "Select the destination in the map"
                end
                [app.start_end_points(1,i),app.start_end_points(2,i)] = ginput(1);
                
                plot(app.start_end_points(1,i),app.start_end_points(2,i),'r+')
            end
            
            pause(0.7);
            close
            
            if ~empty
                cla(app.UIAxes);
                imshow('ist_map_detail.png','Parent',app.UIAxes);
                app.OutputText.Value = "New starting point and destination selected";
            else
                app.OutputText.Value = "Starting point and destination selected";
            end
            
            hold(app.UIAxes, 'on')
            plot(app.start_end_points(1,:),app.start_end_points(2,:),'+k','Parent',app.UIAxes)
            
        end

        % Button pushed function: CalculatepathButton
        function CalculatepathButtonPushed(app, event)
            if isempty(app.start_end_points)
                app.OutputText.Value = "Please select starting point and final destination!"
                return
            end
            tic
            app.OutputText.Value = "Calculating the best path..."
            
            [xq, vq] = get_path(app.start_end_points(:,1), app.start_end_points(:,2), app.G)
            app.x_path = [xq; vq]; 
            hold(app.UIAxes, 'on')
            plot(xq, vq,'Parent',app.UIAxes)
            app.OutputText.Value = "Best path calculated successfully! (Elapsed time " + num2str(toc) +" [s])";
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
            imshow('ist_map_detail.png','Parent',app.UIAxes);
            app.OutputText.Value = "Plot cleared!";
            app.ShowStructureCheckBox.Value = 0
            app.ShowStopsignsCheckBox.Value = 0
            app.ShowSpeedLimitsignsCheckBox.Value = 0
            app.ShowPedestrianCrossingsignsCheckBox.Value = 0
        end

        % Value changed function: ShowStructureCheckBox
        function ShowStructureCheckBoxValueChanged(app, event)
            value = app.ShowStructureCheckBox.Value;
            if value
                app.Ggraph = plot(app.G,'Xdata', app.G.Nodes.X, 'YData', app.G.Nodes.Y,'Parent', app.UIAxes );
            else
                delete(app.Ggraph)
            end
        end

        % Callback function
        function PRINTButtonPushed(app, event)
            app.OutputText.Value = "NOSSO PRINT!";
        end

        % Value changed function: ShowStopsignsCheckBox
        function ShowStopsignsCheckBoxValueChanged(app, event)
            value = app.ShowStopsignsCheckBox.Value;
            
            if value
                app.stop_signs_scatter=scatter( app.UIAxes, app.stop_signs(1,:), app.stop_signs(2,:),25,'MarkerEdgeColor','k',...
              'MarkerFaceColor','r',...
              'LineWidth',0.3);
            else
                delete(app.stop_signs_scatter)
            end
        end

        % Value changed function: ShowSpeedLimitsignsCheckBox
        function ShowSpeedLimitsignsCheckBoxValueChanged(app, event)
            value = app.ShowSpeedLimitsignsCheckBox.Value;
            
            if value
                app.speed_limit_signs_scatter1=scatter( app.UIAxes, app.speed_limit_signs(1,:), app.stop_signs(2,:),27,'MarkerEdgeColor','k',...
              'LineWidth',1);
                app.speed_limit_signs_scatter=scatter( app.UIAxes, app.speed_limit_signs(1,:), app.stop_signs(2,:),20,'MarkerEdgeColor','r',...
              'MarkerFaceColor','w',...
              'LineWidth',0.3);
          
            else
                delete(app.speed_limit_signs_scatter);
                delete(app.speed_limit_signs_scatter1);
            end
        end

        % Value changed function: 
        % ShowPedestrianCrossingsignsCheckBox
        function ShowPedestrianCrossingsignsCheckBoxValueChanged(app, event)
            value = app.ShowPedestrianCrossingsignsCheckBox.Value;
            
            if value

                app.pedestrian_crossing_signs_scatter=scatter( app.UIAxes, app.pedestrian_crossing_signs(1,:), app.pedestrian_crossing_signs(2,:),25,'s','MarkerEdgeColor','b',...
              'MarkerFaceColor','w',...
              'LineWidth',0.3);
          
            else
                delete(app.pedestrian_crossing_signs_scatter);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.OutputText = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationsTab = uitab(app.TabGroup);
            app.SimulationsTab.Title = 'Simulations';
            app.SelectstartandfinishpointsButton = uibutton(app.SimulationsTab, 'push');
            app.SelectstartandfinishpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectstartandfinishpointsButtonPushed, true);
            app.SelectstartandfinishpointsButton.Text = {'Select start and finish points'; ''; ''};
            app.CalculatepathButton = uibutton(app.SimulationsTab, 'push');
            app.CalculatepathButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepathButtonPushed, true);
            app.CalculatepathButton.Text = 'Calculate path';
            app.StartSimulationButton = uibutton(app.SimulationsTab, 'push');
            app.StartSimulationButton.Text = 'Start Simulation';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ShowStructureCheckBox = uicheckbox(app.OptionsTab);
            app.ShowStructureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowStructureCheckBoxValueChanged, true);
            app.ShowStructureCheckBox.Text = 'Show Structure';
            app.ShowStopsignsCheckBox = uicheckbox(app.OptionsTab);
            app.ShowStopsignsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowStopsignsCheckBoxValueChanged, true);
            app.ShowStopsignsCheckBox.Text = 'Show Stop signs';
            app.ShowSpeedLimitsignsCheckBox = uicheckbox(app.OptionsTab);
            app.ShowSpeedLimitsignsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSpeedLimitsignsCheckBoxValueChanged, true);
            app.ShowSpeedLimitsignsCheckBox.Text = 'Show Speed Limit signs';
            app.ShowPedestrianCrossingsignsCheckBox = uicheckbox(app.OptionsTab);
            app.ShowPedestrianCrossingsignsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPedestrianCrossingsignsCheckBoxValueChanged, true);
            app.ShowPedestrianCrossingsignsCheckBox.Text = 'Show Pedestrian Crossing signs';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.BatteryGaugeLabel = uilabel(app.Panel);
            app.BatteryGaugeLabel.Text = 'Battery';
            app.BatteryGauge = uigauge(app.Panel, 'linear');
            app.BatteryGauge.MajorTicks = [0 25 50 75 100];
            app.BatteryGauge.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.BatteryGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.BatteryGauge.Value = 100;
            app.Controls = uipanel(app.Panel);
            app.Controls.Title = 'Controls';
            app.VelocityLabel = uilabel(app.Controls);
            app.VelocityLabel.Text = 'Velocity';
            app.VelocityOutText = uieditfield(app.Controls, 'text');
            app.VelocityOutText.Editable = 'off';
            app.PhiLabel = uilabel(app.Controls);
            app.PhiLabel.Text = {'Phi'; ''};
            app.PhiOutText = uieditfield(app.Controls, 'text');
            app.PhiOutText.Editable = 'off';
            app.StopSimulationsButton = uibutton(app.UIFigure, 'push');
            app.StopSimulationsButton.Text = 'Stop Simulations';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end