classdef UI_test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        SimulationsTab                  matlab.ui.container.Tab
        SelectstartandfinishpointsButton  matlab.ui.control.Button
        CalculatepathButton             matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        BatteryGaugeLabel               matlab.ui.control.Label
        BatteryGauge                    matlab.ui.control.LinearGauge
        Controls                        matlab.ui.container.Panel
        VelocitymsLabel                 matlab.ui.control.Label
        VelocityOutText                 matlab.ui.control.EditField
        PhiDegreesLabel                 matlab.ui.control.Label
        PhiOutText                      matlab.ui.control.EditField
        RealTimeDislayButton            matlab.ui.control.Button
        StopSimDisplayButton            matlab.ui.control.StateButton
        SimTimesEditFieldLabel          matlab.ui.control.Label
        SimTimesEditField               matlab.ui.control.NumericEditField
        ShowPlotsButton                 matlab.ui.control.Button
        ClearPlotButton                 matlab.ui.control.Button
        OptionsTab                      matlab.ui.container.Tab
        ShowStructureCheckBox           matlab.ui.control.CheckBox
        AddStopsignsCheckBox            matlab.ui.control.CheckBox
        AddSpeedLimitsignsCheckBox      matlab.ui.control.CheckBox
        AddPedestrianCrossingsignsCheckBox  matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        PedestriancrossinginfoLabel     matlab.ui.control.Label
        SelectcrossingpositionButton    matlab.ui.control.Button
        AddPedstrianCrossingButton      matlab.ui.control.Button
        StartcrossingtimeEditFieldLabel  matlab.ui.control.Label
        StartcrossingtimeEditField      matlab.ui.control.NumericEditField
        CrossingdurationEditFieldLabel  matlab.ui.control.Label
        CrossingdurationEditField       matlab.ui.control.NumericEditField
        crossing_pos                    matlab.ui.control.EditField
        crossing_pos_2                  matlab.ui.control.EditField
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        OutputText                      matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        
        %SIMULATION VARS
        delta_t = 0.1;
        start_end_points=[]; % Start and finish point coordinates
        G;
        x_path = []
        Ggraph = plot([]);
        MAX_ENERGY = 5000;
        
        %EVENTS
        stop_signs=[];
        stop_signs_scatter;
        
        speed_limit_signs=[];
        speed_limit_signs_scatter;
        speed_limit_signs_scatter1;
        
        pedestrian_crossing_signs=[];
        pedestrian_crossing_signs_scatter;
        
        pedestrian_crossing_pos=[];
        pedestrian_crossing_times=[];
        pedestrian_crossing_pos_aux=[];
        pedestrian_crossing_pos_scatter;
        %SIMULATION OUTPUTS
        phi_save;
        v_save;
        a_save;
        energy_spent;
        X;
        
        %REAL TIME DISPLAY
        real_time_pos_scatter;
        real_time_pos_plot = plot([]);
        
        %UI
        start_end_points_scatter = plot([]);
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes, 'on')
            imshow('ist_map_detail.png','Parent',app.UIAxes);
            
            %LOADS GRAPH STRUCTURE
            app.G = load('G');
            app.G = app.G.G;

            app.OutputText.Enable = 'on';
            app.OutputText.Editable = 'off';
            app.OutputText.Value = "Program initialized successfully!";
        end

        % Button pushed function: SelectstartandfinishpointsButton
        function SelectstartandfinishpointsButtonPushed(app, event)
            
            
            empty = isempty(app.start_end_points);
            app.OutputText.Value = "Select the starting point in the map"
            figure();
            imshow('ist_map_detail.png');
            hold on;
            
            for i=1:2
                if i == 2
                    app.OutputText.Value = "Select the destination in the map"
                end
                [app.start_end_points(1,i),app.start_end_points(2,i)] = ginput(1);
                
                plot(app.start_end_points(1,i),app.start_end_points(2,i),'r+')
            end
            
            pause(0.7);
            close
            
            if ~empty
                %cla(app.UIAxes);
                %imshow('ist_map_detail.png','Parent',app.UIAxes);
                app.OutputText.Value = "New starting point and destination selected";
            else
                app.OutputText.Value = "Starting point and destination selected";
            end
            
            hold(app.UIAxes, 'on')
            %plot(app.start_end_points(1,:),app.start_end_points(2,:),'+k','Parent',app.UIAxes)
            delete(app.start_end_points_scatter);
            app.start_end_points_scatter =plot(app.start_end_points(1,:),app.start_end_points(2,:),...
                '+k','Parent',app.UIAxes);
        end

        % Button pushed function: CalculatepathButton
        function CalculatepathButtonPushed(app, event)
            if isempty(app.start_end_points)
                app.OutputText.Value = "Please select starting point and final destination!"
                return
            end
            tic;
            app.OutputText.Value = "Calculating the best path...";
            
            [xq, vq] = get_path(app.start_end_points(:,1), app.start_end_points(:,2), app.G);
            app.x_path = [xq; vq];
            hold(app.UIAxes, 'on');
            plot(xq, vq,'Parent',app.UIAxes);
            app.OutputText.Value = "Best path calculated successfully! (Elapsed time " + num2str(toc) +" [s])";
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
            imshow('ist_map_detail.png','Parent',app.UIAxes);
            app.OutputText.Value = "Plot cleared!";
            app.ShowStructureCheckBox.Value = 0;
            app.AddStopsignsCheckBox.Value = 0;
            app.AddSpeedLimitsignsCheckBox.Value = 0;
            app.AddPedestrianCrossingsignsCheckBox.Value = 0;
        end

        % Value changed function: ShowStructureCheckBox
        function ShowStructureCheckBoxValueChanged(app, event)
            value = app.ShowStructureCheckBox.Value;
            if value
                app.Ggraph = plot(app.G,'Xdata', app.G.Nodes.X, 'YData', app.G.Nodes.Y,'Parent', app.UIAxes );
            else
                delete(app.Ggraph)
            end
        end

        % Callback function
        function PRINTButtonPushed(app, event)
            
        end

        % Value changed function: AddStopsignsCheckBox
        function AddStopsignsCheckBoxValueChanged(app, event)
            value = app.AddStopsignsCheckBox.Value;
            
            if value
                %LOAD STOP SIGNS POSITIONS
            app.stop_signs = load('stop_signs');
            app.stop_signs = app.stop_signs.stop_signs;
                app.stop_signs_scatter=scatter( app.UIAxes, app.stop_signs(1,:), app.stop_signs(2,:),25,'MarkerEdgeColor','k',...
                    'MarkerFaceColor','r',...
                    'LineWidth',0.3);
            else
                app.stop_signs=[];
                delete(app.stop_signs_scatter)
            end
        end

        % Value changed function: AddSpeedLimitsignsCheckBox
        function AddSpeedLimitsignsCheckBoxValueChanged(app, event)
            value = app.AddSpeedLimitsignsCheckBox.Value;
            
            if value
                %SPEED LIMIT SIGNS
                app.speed_limit_signs = load('speed_limit_signs');
                app.speed_limit_signs = app.speed_limit_signs.speed_limit_signs;
                app.speed_limit_signs_scatter1=scatter( app.UIAxes, app.speed_limit_signs(1,:), app.speed_limit_signs(2,:),27,'MarkerEdgeColor','k',...
                    'LineWidth',1);
                app.speed_limit_signs_scatter=scatter( app.UIAxes, app.speed_limit_signs(1,:), app.speed_limit_signs(2,:),20,'MarkerEdgeColor','r',...
                    'MarkerFaceColor','w',...
                    'LineWidth',0.3);
                
            else
                app.speed_limit_signs=[];
                delete(app.speed_limit_signs_scatter);
                delete(app.speed_limit_signs_scatter1);
            end
        end

        % Value changed function: AddPedestrianCrossingsignsCheckBox
        function AddPedestrianCrossingsignsCheckBoxValueChanged(app, event)
            value = app.AddPedestrianCrossingsignsCheckBox.Value;
            
            if value
                app.pedestrian_crossing_signs = load('pedestrian_crossing_signs');
                app.pedestrian_crossing_signs = app.pedestrian_crossing_signs.pedestrian_crossing_signs;
                
                app.pedestrian_crossing_signs_scatter=scatter( app.UIAxes, app.pedestrian_crossing_signs(1,:), app.pedestrian_crossing_signs(2,:),25,'s','MarkerEdgeColor','b',...
                    'MarkerFaceColor','w',...
                    'LineWidth',0.3);
                
            else
                app.pedestrian_crossing_signs=[];
                delete(app.pedestrian_crossing_signs_scatter);
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.OutputText.Value = "Simulation Started!";
            tic;
            [app.phi_save,app.v_save, app.a_save, app.energy_spent, app.X, flag] =...
                robot_simulation(app.G, app.start_end_points(:,1), app.x_path, app.stop_signs, ...
                app.speed_limit_signs, app.pedestrian_crossing_signs, app.pedestrian_crossing_pos, app.pedestrian_crossing_times, app.MAX_ENERGY);
            
            if flag ==1
                app.OutputText.Value = "Simulation completed successfully! (Elapsed time " + num2str(toc) +" [s])";
            else
                app.OutputText.Value = "Not enough battery! (Elapsed time " + num2str(toc) +" [s])";
            end
        end

        % Button pushed function: RealTimeDislayButton
        function RealTimeDislayButtonPushed(app, event)
            app.OutputText.Value = "Real time simulation display started" +...
                newline + "Running . . .";

            d = tic;
            for i= 1:6:size(app.X, 2)
                b =tic;
                pedestrian_display(i,app.delta_t, app.pedestrian_crossing_pos, app.pedestrian_crossing_times, app)
                
                if app.StopSimDisplayButton.Value == true
                    app.StopSimDisplayButton.Value = false;
                    app.OutputText.Value = "Simulation display stopped! (Elapsed time " + num2str(toc(d)) +" [s])";
                    return
                end
                app.real_time_pos_scatter = scatter(app.UIAxes, app.X(1,i), app.X(2,i),...
                    'sk');
                
                app.VelocityOutText.Value = app.v_save(i)/app.delta_t +"";
                app.PhiOutText.Value = sprintf('%2.1f',rad2deg(app.phi_save(i))) + "";
                current_energy = (app.MAX_ENERGY - sum(app.energy_spent(1:i)))/app.MAX_ENERGY*100;
                app.BatteryGauge.Value = current_energy;
                
                app.SimTimesEditField.Value = i*app.delta_t;
                a = toc(b);
                app.real_time_pos_plot = plot(app.UIAxes, app.X(1,1:i), app.X(2,1:i), 'r');
                pause(0.1-a);
                drawnow;
                delete(app.real_time_pos_scatter);  
                delete(app.real_time_pos_plot)    
            end
            app.VelocityOutText.Value ='';
            app.PhiOutText.Value ='';
            app.SimTimesEditField.Value = 0;
            app.OutputText.Value = "Simulation completed (Travel time " +size(app.X, 2)*app.delta_t +" [s])";
            
        end

        % Button pushed function: SelectcrossingpositionButton
        function SelectcrossingpositionButtonPushed(app, event)
        figure();
        imshow('ist_map_detail.png');hold on;   
        [app.pedestrian_crossing_pos_aux(1,1),app.pedestrian_crossing_pos_aux(2,1)] = ginput(1);
        app.crossing_pos.Value = app.pedestrian_crossing_pos_aux(1,1)+"";
        app.crossing_pos_2.Value = app.pedestrian_crossing_pos_aux(2,1)+"";
        
        plot(app.pedestrian_crossing_pos_aux(1,1),app.pedestrian_crossing_pos_aux(2,1),'+r')     
        pause(0.7);
        close
        end

        % Button pushed function: AddPedstrianCrossingButton
        function AddPedstrianCrossingButtonPushed(app, event)
            app.pedestrian_crossing_times(:,end+1) =[ app.StartcrossingtimeEditField.Value; ...
                app.CrossingdurationEditField.Value];
            app.pedestrian_crossing_pos(:,end+1) = app.pedestrian_crossing_pos_aux;
            app.OutputText.Value = "Pedestrian added successfully!";
            app.pedestrian_crossing_pos_scatter = scatter(app.UIAxes,app.pedestrian_crossing_pos(1,end), app.pedestrian_crossing_pos(2,end), 'filled', 'd', 'blue');
        end

        % Button pushed function: ShowPlotsButton
        function ShowPlotsButtonPushed(app, event)
            if isempty(app.a_save)
                app.OutputText.Value = "You need to run the simulation first!";
                return
            end
            figure();
            plot( (1:length(app.a_save))*app.delta_t, (app.v_save/app.delta_t) );
            hold on
            plot( (1:length(app.a_save))*app.delta_t, app.a_save/app.delta_t);
            xlabel("Time (s)");
            legend("Velocity [m/s]", "Acceleration [m\^2/s]");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationsTab = uitab(app.TabGroup);
            app.SimulationsTab.Title = 'Simulations';
            app.SelectstartandfinishpointsButton = uibutton(app.SimulationsTab, 'push');
            app.SelectstartandfinishpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectstartandfinishpointsButtonPushed, true);
            app.SelectstartandfinishpointsButton.Tooltip = {''};
            app.SelectstartandfinishpointsButton.Text = {'Select start and finish points'; ''; ''};
            app.CalculatepathButton = uibutton(app.SimulationsTab, 'push');
            app.CalculatepathButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepathButtonPushed, true);
            app.CalculatepathButton.Text = 'Calculate path';
            app.SimulateButton = uibutton(app.SimulationsTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Button = uibutton(app.SimulationsTab, 'push');
            app.Button.Tooltip = {'Select a start and finish point of the path. The first input is the starting point and the second input the destination!'};
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.SimulationsTab, 'push');
            app.Button_2.Tooltip = {'Calculates the path between the selected start and finish point. A pair of points must be selected before calculating the path. '};
            app.Button_2.Text = '?';
            app.Button_3 = uibutton(app.SimulationsTab, 'push');
            app.Button_3.Tooltip = {'Simulates the car position and its controls between the given pair of points. A pair of points must be selected and the respective path calculated.'};
            app.Button_3.Text = '?';
            app.Panel = uipanel(app.SimulationsTab);
            app.BatteryGaugeLabel = uilabel(app.Panel);
            app.BatteryGaugeLabel.Text = 'Battery';
            app.BatteryGauge = uigauge(app.Panel, 'linear');
            app.BatteryGauge.MajorTicks = [0 25 50 75 100];
            app.BatteryGauge.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.BatteryGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.BatteryGauge.Value = 100;
            app.Controls = uipanel(app.Panel);
            app.Controls.Title = 'Controls';
            app.VelocitymsLabel = uilabel(app.Controls);
            app.VelocitymsLabel.Text = 'Velocity [m/s]';
            app.VelocityOutText = uieditfield(app.Controls, 'text');
            app.VelocityOutText.Editable = 'off';
            app.PhiDegreesLabel = uilabel(app.Controls);
            app.PhiDegreesLabel.Text = {'Phi [Degrees]'; ''};
            app.PhiOutText = uieditfield(app.Controls, 'text');
            app.PhiOutText.Editable = 'off';
            app.RealTimeDislayButton = uibutton(app.Panel, 'push');
            app.RealTimeDislayButton.ButtonPushedFcn = createCallbackFcn(app, @RealTimeDislayButtonPushed, true);
            app.RealTimeDislayButton.Tooltip = {'A real time display of the simulation!'};
            app.RealTimeDislayButton.Text = 'Real Time Dislay';
            app.StopSimDisplayButton = uibutton(app.Panel, 'state');
            app.StopSimDisplayButton.Tooltip = {'Use only when a simulation is running.'};
            app.StopSimDisplayButton.Text = 'Stop Sim Display';
            app.SimTimesEditFieldLabel = uilabel(app.Panel);
            app.SimTimesEditFieldLabel.Text = 'Sim Time [s]';
            app.SimTimesEditField = uieditfield(app.Panel, 'numeric');
            app.SimTimesEditField.Editable = 'off';
            app.ShowPlotsButton = uibutton(app.Panel, 'push');
            app.ShowPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPlotsButtonPushed, true);
            app.ShowPlotsButton.Text = 'Show Plots';
            app.ClearPlotButton = uibutton(app.SimulationsTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ShowStructureCheckBox = uicheckbox(app.OptionsTab);
            app.ShowStructureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowStructureCheckBoxValueChanged, true);
            app.ShowStructureCheckBox.Text = 'Show Structure';
            app.AddStopsignsCheckBox = uicheckbox(app.OptionsTab);
            app.AddStopsignsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddStopsignsCheckBoxValueChanged, true);
            app.AddStopsignsCheckBox.Text = 'Add Stop signs';
            app.AddSpeedLimitsignsCheckBox = uicheckbox(app.OptionsTab);
            app.AddSpeedLimitsignsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddSpeedLimitsignsCheckBoxValueChanged, true);
            app.AddSpeedLimitsignsCheckBox.Text = 'Add Speed Limit signs';
            app.AddPedestrianCrossingsignsCheckBox = uicheckbox(app.OptionsTab);
            app.AddPedestrianCrossingsignsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddPedestrianCrossingsignsCheckBoxValueChanged, true);
            app.AddPedestrianCrossingsignsCheckBox.Text = 'Add Pedestrian Crossing signs';
            app.Panel_2 = uipanel(app.OptionsTab);
            app.PedestriancrossinginfoLabel = uilabel(app.Panel_2);
            app.PedestriancrossinginfoLabel.Text = 'Pedestrian crossing info:';
            app.SelectcrossingpositionButton = uibutton(app.Panel_2, 'push');
            app.SelectcrossingpositionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcrossingpositionButtonPushed, true);
            app.SelectcrossingpositionButton.Text = 'Select crossing position';
            app.AddPedstrianCrossingButton = uibutton(app.Panel_2, 'push');
            app.AddPedstrianCrossingButton.ButtonPushedFcn = createCallbackFcn(app, @AddPedstrianCrossingButtonPushed, true);
            app.AddPedstrianCrossingButton.Text = 'Add';
            app.StartcrossingtimeEditFieldLabel = uilabel(app.Panel_2);
            app.StartcrossingtimeEditFieldLabel.Text = 'Start crossing time';
            app.StartcrossingtimeEditField = uieditfield(app.Panel_2, 'numeric');
            app.CrossingdurationEditFieldLabel = uilabel(app.Panel_2);
            app.CrossingdurationEditFieldLabel.Text = 'Crossing duration';
            app.CrossingdurationEditField = uieditfield(app.Panel_2, 'numeric');
            app.crossing_pos = uieditfield(app.Panel_2, 'text');
            app.crossing_pos.Enable = 'off';
            app.crossing_pos_2 = uieditfield(app.Panel_2, 'text');
            app.crossing_pos_2.Enable = 'off';
            app.XLabel = uilabel(app.Panel_2);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.Panel_2);
            app.YLabel.Text = 'Y:';
            app.OutputText = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end